classdef Plot_Serial_App_nopopup < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                        matlab.ui.Figure
        PlotSerialInDataLabel          matlab.ui.control.Label
        SerialCommunicationsDataPanel  matlab.ui.container.Panel
        Setto123toturnLEDonLabel       matlab.ui.control.Label
        text6                          matlab.ui.control.Label
        editSerialIn                   matlab.ui.control.EditField
        text3                          matlab.ui.control.Label
        editSerialOut                  matlab.ui.control.EditField
        ControlsPanel                  matlab.ui.container.Panel
        ChooseUSBDropDown              matlab.ui.control.DropDown
        ChooseUSBDropDownLabel         matlab.ui.control.Label
        pushbuttonStop                 matlab.ui.control.Button
        writingtoafileLabel            matlab.ui.control.Label
        PressButtontobeginLabel        matlab.ui.control.Label
        pushbuttonFile                 matlab.ui.control.Button
        axesTemp                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        serialPort      % Object for Serial Port
        hTempPlot       % Handle to Plot in Axes
        arduinoVals     % Values to be sent to the Arduino
        serialInBuffer  % Buffered data collected from reading serial data
        timeBuffer      % Buffered data collected from reading serial time
        dataFile        % File to save data to
    end
    
    methods (Access = private)
                
        function instrcallback(app, ~, ~)
            % INSTRCALLBACK-callback for serial.BytesAvailableFunction
            % This gets called whenever new data is available on 
            % the serial port.
    
            a = readline(app.serialPort);
            % Read and decode data from serial port formatted in JSON
                try % Error handling in case the JSON message is poorly formed
                    data = jsondecode(a);
                catch ME
                    fprintf(2,'Error thrown in JSON decode\n');
                    fprintf(2,'JSON msg poorly formatted?\n');
                    fprintf(2,'%s\n', ME.message);
                    return
                end
    
                % Gather data to update axes
                app.serialInBuffer= circshift(app.serialInBuffer,1);
                app.serialInBuffer(1) = data.temperatureInside;
                app.timeBuffer = circshift(app.timeBuffer,1);
                app.timeBuffer(1) = data.time/1000;
                % Update Plots
                app.axesTemp.XLim = [app.timeBuffer(end), app.timeBuffer(1)];
                app.hTempPlot.XData = app.timeBuffer;
                app.hTempPlot.YData = app.serialInBuffer;
                drawnow()
    
                % Update textbox values
                app.editSerialIn.Value = num2str(data.temperatureInside);
    
                % Save data to disk
                if ~isnan(app.dataFile)
                    save_data(app, app.serialInBuffer(1),...
                              app.timeBuffer(1));
                end
        end
        

        function save_data(app, tIn, time)
            % Save data to the open csv file. This file is opened in
            % PBfile_Callback function.
            writematrix([tIn,time], app.dataFile, 'WriteMode', 'append')
        end
        

        function setup_serial(app, comPort)
              % Initialize Serial object
              app.serialPort = serialport(comPort, 9600);
              %set(obj,'DataBits',8);
              %set(obj,'StopBits',1);
              %set(obj,'Parity','none');
              configureCallback(app.serialPort,"terminator", @app.instrcallback)
        end
        

        function updateArduinoVals(app)
                % update the PID and temperature setpoint values in the arduino
                serialData = jsonencode(app.arduinoVals);
                disp("Serial Data is:") % For DEBUG
                disp(serialData)        % For DEBUG
            
                writeline(app.serialPort,serialData);
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Plot_Serial_OpeningFcn(app, varargin)
            % --- Executes just before Plot_Serial is made visible.
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Reset the com ports in case the Arduino was not properly disconnected
            % before the program quit previously.
            clear app.serialPort
            
            % Allocate buffers and buffer size
            app.serialInBuffer = zeros(100,1);
            app.timeBuffer = zeros(size(app.serialInBuffer));

            % Setting app.dataFile to NaN prevents saving data until
            % the "Write to File" Pushbutton is used.
            app.dataFile = NaN;
        
            % Plot for the data recieved over serial port
            app.hTempPlot = plot(app.axesTemp, app.timeBuffer, app.serialInBuffer);
            set(app.axesTemp, 'YLim', [0,25]);
            title('Temperature');
            app.axesTemp.YLabel.String = 'Degrees Celsius';
            app.axesTemp.XLabel.String = 'Time (s)';
            legend('Temp Inside', 'Location', 'southwest');
            

        end

        % Button pushed function: pushbuttonFile
        function pushbuttonFile_Callback(app, event)
            [filename,pathname] = uiputfile('*.csv', 'Select file to save data');
            app.dataFile = fullfile(pathname, filename);
            fid = fopen(app.dataFile,'w');
            fprintf(fid,'InsideTempC,TimeS\n');
            fclose(fid);
        end

        % Button pushed function: pushbuttonStop
        function pushbuttonStop_Callback(app, event)
            % --- Executes on button press in pushbuttonStop.
            clear app.serialPort
            app.delete

        end

        % Drop down opening function: ChooseUSBDropDown
        function ChooseUSBDropDownOpening(app, event)
            % Get the list of available ports connected to the computer
                ports = cellstr(serialportlist);
            
                % Prepend 'none' selection to ports cell array to allow for running
                %  the UI without a connected Arduino, FUTURE FEATURE
                ports = [{'none'},ports];
                app.ChooseUSBDropDown.Items = ports;
        end

        % Value changed function: ChooseUSBDropDown
        function ChooseUSBDropDownValueChanged(app, event)
            comPort = app.ChooseUSBDropDown.Value;
            setup_serial(app, comPort);
        end

        % Value changed function: editSerialOut
        function editSerialOutValueChanged(app, event)
            app.arduinoVals.pVal = app.editSerialOut.Value;
            app.updateArduinoVals()
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Plot_Serial';
            app.figure1.Tag = 'figure1';
            app.axesTemp = uiaxes(app.figure1);
            app.axesTemp.Tag = 'axesTemp';
            app.ControlsPanel = uipanel(app.figure1);
            app.ControlsPanel.Title = 'Controls';
            app.pushbuttonFile = uibutton(app.ControlsPanel, 'push');
            app.pushbuttonFile.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonFile_Callback, true);
            app.pushbuttonFile.Tag = 'PBfile';
            app.pushbuttonFile.Text = 'Write to File';
            app.PressButtontobeginLabel = uilabel(app.ControlsPanel);
            app.PressButtontobeginLabel.Text = 'Press Button to begin ';
            app.writingtoafileLabel = uilabel(app.ControlsPanel);
            app.writingtoafileLabel.Text = 'writing to a file. ';
            app.pushbuttonStop = uibutton(app.ControlsPanel, 'push');
            app.pushbuttonStop.ButtonPushedFcn = createCallbackFcn(app, @pushbuttonStop_Callback, true);
            app.pushbuttonStop.Tag = 'pushbuttonStop';
            app.pushbuttonStop.Text = 'Stop';
            app.ChooseUSBDropDownLabel = uilabel(app.ControlsPanel);
            app.ChooseUSBDropDownLabel.Text = 'Choose USB';
            app.ChooseUSBDropDown = uidropdown(app.ControlsPanel);
            app.ChooseUSBDropDown.Items = {'click me'};
            app.ChooseUSBDropDown.DropDownOpeningFcn = createCallbackFcn(app, @ChooseUSBDropDownOpening, true);
            app.ChooseUSBDropDown.ValueChangedFcn = createCallbackFcn(app, @ChooseUSBDropDownValueChanged, true);
            app.ChooseUSBDropDown.Value = 'click me';
            app.SerialCommunicationsDataPanel = uipanel(app.figure1);
            app.SerialCommunicationsDataPanel.Title = 'Serial Communications Data';
            app.editSerialOut = uieditfield(app.SerialCommunicationsDataPanel, 'text');
            app.editSerialOut.ValueChangedFcn = createCallbackFcn(app, @editSerialOutValueChanged, true);
            app.editSerialOut.Tag = 'editSerialOut';
            app.editSerialOut.Value = '20';
            app.text3 = uilabel(app.SerialCommunicationsDataPanel);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Serial Out';
            app.editSerialIn = uieditfield(app.SerialCommunicationsDataPanel, 'text');
            app.editSerialIn.Tag = 'editSerialIn';
            app.text6 = uilabel(app.SerialCommunicationsDataPanel);
            app.text6.Tag = 'text6';
            app.text6.Text = 'Serial In';
            app.Setto123toturnLEDonLabel = uilabel(app.SerialCommunicationsDataPanel);
            app.Setto123toturnLEDonLabel.Text = 'Set to 1.23 to turn LED on';
            app.PlotSerialInDataLabel = uilabel(app.figure1);
            app.PlotSerialInDataLabel.Text = 'Plot Serial In Data';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Plot_Serial_App_nopopup(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)Plot_Serial_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end